classdef WidefieldExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SL                              matlab.ui.control.Label
        hpctf                           matlab.ui.control.NumericEditField
        HighPassCutoffHzEditFieldLabel  matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        UIAxes1                         matlab.ui.control.UIAxes
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        vars % stores variables
        atlas_image % reference atlas image
        bluelin % higher axis
        redlin % lower axis
        px % colored pixels
        filt % filtered traces
    end

    methods (Access = private)
        function updateAtlas(app,src,event)
            clickXY = round(event.IntersectionPoint(1:2));
            currLab = app.vars.dca.labs(clickXY(2),clickXY(1));
            xd = app.vars.pd.FT;
            yd = app.vars.pd.dft(currLab,:);
            idxoffset = numel(app.vars.dca.labs); % index color offset value
            switch event.Button
                case 1 % left mouse button BLUE
                    app.bluelin.XData = xd; % update xdata
                    app.bluelin.YData = yd; % update ydata
                    app.filt.bluelin.XData = xd;
                    app.filt.bluelin.YData = yd;

                    %---------------Reset blues back to white------------------%
                    app.atlas_image.CData(app.px.blue) = 1; % reset red to 1
                    app.atlas_image.CData(app.px.blue+idxoffset) = 1; % reset green to 1

                    %---------------Find new indices and set to blue------------------%
                    app.px.blue = find(app.vars.dca.labs==currLab); % find pixel indices
                    app.atlas_image.CData(app.px.blue) = 0; % set red to 0
                    app.atlas_image.CData(app.px.blue+idxoffset) = 0; % set green to 0
                    fprintf('Blue is %d\n',currLab);
                case 3 % right mouse button RED
                    app.redlin.XData = xd;
                    app.redlin.YData = yd;
                    app.filt.redlin.XData = xd;
                    app.filt.redlin.YData = yd;

                    %---------------Reset reds back to white------------------%
                    app.atlas_image.CData(app.px.red+idxoffset) = 1; % reset green to 1
                    app.atlas_image.CData(app.px.red+idxoffset*2) = 1; % reset blue to 1

                    %---------------Find new indices and set to red------------------%
                    app.px.red = find(app.vars.dca.labs==currLab); % find pixel indices
                    app.atlas_image.CData(app.px.red+idxoffset) = 0; % set red to 0
                    app.atlas_image.CData(app.px.red+idxoffset*2) = 0; % set green to 0
                    fprintf('Red is %d\n',currLab);
            end
            hpctfValueChanged(app, event);
        end
        
        function results = IdleBusy(app)
            if strcmp(app.SL.Text,'Idle')
                app.SL.Text = 'Busy...';
            else
                app.SL.Text = 'Idle';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            IdleBusy(app);
            linkaxes([app.UIAxes2, app.UIAxes2_2, app.UIAxes2_3],'x');
            load('dorsalCortexAtlas.mat','dca');    % load the atlas data
            app.vars.dca = dca;
            rgbImage = double(cat(3,~dca.bw_atlas,~dca.bw_atlas,~dca.bw_atlas));
            app.atlas_image = imshow(rgbImage, ...
                'Parent', app.UIAxes1);
            app.atlas_image.ButtonDownFcn = @(src,event) app.updateAtlas(src,event);
            % --- Initialize raw dF/F plot --- %
            app.bluelin = plot(app.UIAxes2_2,0,0,'b');
            hold(app.UIAxes2_2,'on');
            app.redlin = plot(app.UIAxes2_2,0,0,'r');
            hold(app.UIAxes2_2,'off');

            % --- Initialize filtered dF/F plot --- %
            app.filt.bluelin = plot(app.UIAxes2_3,0,0,'b');
            hold(app.UIAxes2_3,'on');
            app.filt.redlin = plot(app.UIAxes2_3,0,0,'r');
            hold(app.UIAxes2_3,'off');

            app.px.blue = [];
            app.px.red = [];
            IdleBusy(app);
        end

        % Button pushed function: LoadDataButton
        function loadPD(app, event)
            IdleBusy(app)
            [fn,fp] = uigetfile;
            app.LoadDataButton.Text = 'Loading data...';
            drawnow;
            load(fullfile(fp,fn),'pd');
            app.vars.pd = pd;
            plot(app.UIAxes2,app.vars.pd.eeg.tv,app.vars.pd.eeg.data(:,1),'k');
            app.UIAxes2.XLim = [app.vars.pd.eeg.tv(1), app.vars.pd.eeg.tv(end)];
            app.LoadDataButton.Text = 'Load Data';
            IdleBusy(app)
        end

        % Value changed function: hpctf
        function hpctfValueChanged(app, event)
            IdleBusy(app)
            Fc = app.hpctf.Value;
            Fs = 1/diff(app.vars.pd.FT(1:2));
            Wn = Fc / (Fs / 2); % Normalized cutoff (0 to 1)
            if Fc == 0
                return
            else
                [b, a] = butter(5, Wn, 'high'); % construct filter
            end
            
            % --- Perform filtering and update plots --- %
            if app.bluelin.YData ~= 0 
                app.filt.bluelin.YData = filtfilt(b,a,app.bluelin.YData);
            end
            if app.redlin.YData ~= 0
                app.filt.redlin.YData = filtfilt(b,a,app.redlin.YData);
            end
            IdleBusy(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'EEG Signal')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'dF/F Signal')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            title(app.UIAxes2_3, 'High-pass filtered dF/F')
            ylabel(app.UIAxes2_3, 'Y')
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes2_3.XTick = [];
            app.UIAxes1 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @loadPD, true);
            app.LoadDataButton.Text = 'Load Data';
            app.HighPassCutoffHzEditFieldLabel = uilabel(app.UIFigure);
            app.HighPassCutoffHzEditFieldLabel.Text = 'High Pass Cutoff (Hz)';
            app.hpctf = uieditfield(app.UIFigure, 'numeric');
            app.hpctf.ValueChangedFcn = createCallbackFcn(app, @hpctfValueChanged, true);
            app.SL = uilabel(app.UIFigure);
            app.SL.Text = 'Idle';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WidefieldExplorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end